classdef basic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Q1Tab                         matlab.ui.container.Tab
        WhatareweplottingButtonGroup  matlab.ui.container.ButtonGroup
        SquaresButton                 matlab.ui.control.RadioButton
        NeedlesButton                 matlab.ui.control.RadioButton
        BestagonsButton               matlab.ui.control.RadioButton
        NeedleCountSpinnerLabel       matlab.ui.control.Label
        NeedleCountSpinner            matlab.ui.control.Spinner
        UIAxes                        matlab.ui.control.UIAxes
        Q2Tab                         matlab.ui.container.Tab
    end

    
    properties (Access = private)
		S	=	1					% Scale factor
        N	=	1000				% Number of needles
		NoP	=	10					% Number of planks
		D							% Distance between planks
		SL							% Length of square side
		
		% Patch object
		p
		
		% State
		sq_angles
		xc
		yc
		xcr
		ycr
    end
    
    methods (Access = private)
        
		function updatePlankCount(app, plankCount)
			app.NoP = plankCount;
			app.D =	app.S / app.NoP;
			
			updatePlot(app);
		end
        
		function updateSquareCount(app, squareCount)
			app.N = squareCount;
			app.sq_angles = rand(1, app.N) * 360;
			app.xc = rand(1, app.N);
			app.yc = rand(1, app.N);
			
			updateSquarePlot(app);
		end
		
        function updateSquarePlot(app)
			updatePlotFloor(app);
			
			delete(app.p);
			
			app.xcr = [...
				app.SL * cosd(app.sq_angles) + app.SL * sind(app.sq_angles) + app.xc;...
				app.SL * cosd(app.sq_angles) - app.SL * sind(app.sq_angles) + app.xc;...
				-app.SL * cosd(app.sq_angles) - app.SL * sind(app.sq_angles) + app.xc;...
				-app.SL * cosd(app.sq_angles) + app.SL * sind(app.sq_angles) + app.xc;...
			];
			app.ycr = [...
				-app.SL * sind(app.sq_angles) + app.SL * cosd(app.sq_angles) + app.yc;...
				-app.SL * sind(app.sq_angles) - app.SL * cosd(app.sq_angles) + app.yc;...
				app.SL * sind(app.sq_angles) - app.SL * cosd(app.sq_angles) + app.yc;...
				app.SL * sind(app.sq_angles) + app.SL * cosd(app.sq_angles) + app.yc;...
			];
			
			calculateSquarePi(app);
			
			% Plot all the things
			app.p = patch(app.UIAxes, app.xcr, app.ycr, 'red');
		end
		
		function calculateSquarePi(app)
			n = 0;
			n = n + sum(floor(app.xcr(1, :)/app.D) ~= floor(app.xcr(2, :)/app.D)) + ...
					sum(floor(app.xcr(2, :)/app.D) ~= floor(app.xcr(3, :)/app.D)) + ...
					sum(floor(app.xcr(3, :)/app.D) ~= floor(app.xcr(4, :)/app.D)) + ...
					sum(floor(app.xcr(4, :)/app.D) ~= floor(app.xcr(1, :)/app.D));
			t = app.N*4;
			
			pi_estimate = t/n;
			disp(pi_estimate);
		end
		
		function updatePlotFloor(app)
			for i = 0:app.D:app.S
				xline(app.UIAxes, i,  '-', 'LineWidth', 2)
			end
		end
	end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.D	=	app.S / app.NoP;
			app.SL	=	app.D / 4;
        end

        % Value changed function: NeedleCountSpinner
        function NeedleCountSpinnerValueChanged(app, event)
            value = ceil(app.NeedleCountSpinner.Value);
            updateSquareCount(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Q1Tab = uitab(app.TabGroup);
            app.Q1Tab.Title = 'Q1';
            app.WhatareweplottingButtonGroup = uibuttongroup(app.Q1Tab);
            app.WhatareweplottingButtonGroup.Title = 'What are we plotting?';
            app.SquaresButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.SquaresButton.Text = 'Squares';
            app.SquaresButton.Value = true;
            app.NeedlesButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.NeedlesButton.Text = 'Needles';
            app.BestagonsButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.BestagonsButton.Text = 'Bestagons';
            app.NeedleCountSpinnerLabel = uilabel(app.Q1Tab);
            app.NeedleCountSpinnerLabel.Text = 'Needle Count';
            app.NeedleCountSpinner = uispinner(app.Q1Tab);
            app.NeedleCountSpinner.Limits = [0 10000000];
            app.NeedleCountSpinner.ValueChangedFcn = createCallbackFcn(app, @NeedleCountSpinnerValueChanged, true);
            app.NeedleCountSpinner.Value = 1000;
            app.UIAxes = uiaxes(app.Q1Tab);
            title(app.UIAxes, 'Floor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Q2Tab = uitab(app.TabGroup);
            app.Q2Tab.Title = 'Q2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end